classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TakePhotoButton                 matlab.ui.control.Button
        ImportPhotoButton               matlab.ui.control.Button
        DetectButton                    matlab.ui.control.Button
        VehicleAwarenessDetectionLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        StatusLabel                     matlab.ui.control.Label
        Countdown                       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        image = 0;
        file = '';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakePhotoButton
        function TakePhotoButtonPushed(app, event)
           %
            cam = webcam;
     
            vidRes = cam.VideoResolution;
            imWidth = vidRes(1);
            imHeight = vidRes(2);
            nBands = cam.NumberOfBands;
            hImage = image( zeros(imHeight, imWidth, nBands) );
            
            figSize = get(ax,'Position');
            figWidth = figSize(3);
            figHeight = figSize(4);
            gca.unit = 'pixels';
            gca.position = [ ((figWidth - imWidth)/2)... 
               ((figHeight - imHeight)/2)...
               imWidth imHeight ];
      
            preview(cam,hImage);
            
            set(cam,'FramesPerTrigger', 15);
            cam.TriggerFrameDelay;
            img = cam.getsnapshot();
            imshow(app.image, 'parent', ax);
            figure(app.UIFigure);


        end

        % Button pushed function: ImportPhotoButton
        function ImportPhotoButtonPushed(app, event)
            [app.file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            app.image= imread(app.file);
            ax = uiaxes(app.RightPanel,'Position',[50 150 300 300]);
            imshow(app.image, 'parent', ax);
            figure(app.UIFigure);
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if strcmp(app.file,'')
                app.TextArea.FontSize = 34;
                app.TextArea.HorizontalAlignment = 'center';
                app.TextArea.FontWeight = 'bold';
                app.TextArea.Value = "No picture selected";
            else
                main(app.file);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TakePhotoButton = uibutton(app.LeftPanel, 'push');
            app.TakePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakePhotoButtonPushed, true);
            app.TakePhotoButton.Text = 'Take Photo';
            app.ImportPhotoButton = uibutton(app.LeftPanel, 'push');
            app.ImportPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPhotoButtonPushed, true);
            app.ImportPhotoButton.Text = 'Import Photo';
            app.DetectButton = uibutton(app.LeftPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.VehicleAwarenessDetectionLabel = uilabel(app.LeftPanel);
            app.VehicleAwarenessDetectionLabel.Text = {'Vehicle Awareness '; 'Detection'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Status';
            app.Countdown = uitextarea(app.RightPanel);
            app.Countdown.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end